classdef AppDisign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        Predictiononemonth       matlab.ui.control.CheckBox
        Savetofile               matlab.ui.control.CheckBox
        StartDayDatePickerLabel  matlab.ui.control.Label
        StartDay                 matlab.ui.control.DatePicker
        Selectdata               matlab.ui.container.ButtonGroup
        Cases                    matlab.ui.control.RadioButton
        Deaths                   matlab.ui.control.RadioButton
        Title                    matlab.ui.control.Label
        PLOTButton               matlab.ui.control.Button
        CountriesListBoxLabel    matlab.ui.control.Label
        CountriesListBox         matlab.ui.control.ListBox
        SearchEditFieldLabel     matlab.ui.control.Label
        SearchEditField          matlab.ui.control.EditField
        SelectedCountriesLabel   matlab.ui.control.Label
        SelectedCountries        matlab.ui.control.ListBox
        EndDayLabel              matlab.ui.control.Label
        EndDay                   matlab.ui.control.DatePicker
    end

    
    properties (Access = private)
        Functions           %zbior funkcji llokalnych do obsługi danych
        All_country_names   %zbior wszystkich nazw krajow
        All_data            %zbiór wszystkich danych pobranych ze strony
        Countries_conteiner %obiekt typu conteiner countries
        Url                 %addres url do strony z danymi
        Save_plot           %wykres do zapisywania
        
    end
    
    methods (Access = private)
        
        
        %metoda do zapisywania wykresu do pliku
        function [] = save_1(app,x,y,da,f)
                if(app.Predictiononemonth.Value==1)
                plot(app.UIAxes,[datetime(x) datetime(da)],[y f]);
                else
                plot(datetime(x),y);
                end
            hold on;
            
            
        end


        function [] = save_2(app)
            hold off;
            
            lgd = legend(app.UIAxes.Legend.String);
            lgd.Box = app.UIAxes.Legend.Box;
            lgd.Location = app.UIAxes.Legend.Location;
            app.Save_plot.CurrentAxes.YLabel.String = app.UIAxes.YLabel.String;
            app.Save_plot.CurrentAxes.YLabel.FontSize = app.UIAxes.YLabel.FontSize;
            app.Save_plot.CurrentAxes.XLabel.String = app.UIAxes.XLabel.String;
            app.Save_plot.CurrentAxes.XLabel.FontSize = app.UIAxes.XLabel.FontSize;
            app.Save_plot.CurrentAxes.Title.String = app.UIAxes.Title.String;
            app.Save_plot.CurrentAxes.Title.FontSize = app.UIAxes.Title.FontSize;
            saveas(app.Save_plot,'plot','jpg')
            
            delete(app.Save_plot)
        end
        
        function [da,f] = prediction(app,x,y)
            siz=size(x);
            x_l=linspace(1,siz(2),siz(2));
         
            xx=linspace(siz(2),siz(2)+30,31);
            f=fit(x_l,y,xx);
           
            da=app.Functions.predictdate(30);
           

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Url='https://covid19.who.int/WHO-COVID-19-global-data.csv';
            app.Functions=file_management();
            app.All_data=app.Functions.DownloadData(app.Url);
            app.All_country_names=app.Functions.GetCountryNames(app.All_data);
            app. Countries_conteiner=Container_countries();
            app.CountriesListBox.Items = string(app.All_country_names);
            app.StartDay.Limits = [datetime([2020 1 3]) date];
            
            app.EndDay.Limits = [datetime([2020 1 3]) date];
            app.EndDay.Value = datetime(date);


            app.UIAxes.XLabel.String='Date';
            app.UIAxes.YLabel.String='Number of people';
            
        end

        % Value changed function: CountriesListBox
        function CountriesListBoxValueChanged(app, event)
               inx=strcmp(app.SelectedCountries.Items, app.CountriesListBox.Value);
               app.SelectedCountries.Items(inx)=[]; 
               app.SelectedCountries.Items = [app.SelectedCountries.Items, app.CountriesListBox.Value]; 
              
            %dodawanie elementyw do boxa z wybranymi krajami
        end

        % Value changing function: SearchEditField
        function SearchEditFieldValueChanging(app, event)
            %metoda do wyszukiwania krajów
            changingValue = event.Value;
            names={};
            length_names=1;
            for i=1:length(app.All_country_names)
                if(contains(app.All_country_names{i},changingValue,'IgnoreCase',true))
                    names{length_names}=app.All_country_names{i};
                    length_names=length_names+1;
                end
            end
            app.CountriesListBox.Items = string(names);
        end

        % Value changed function: SelectedCountries
        function SelectedCountriesValueChanged(app, event)
            %usuwanie elementow z boxa z wybranymi krajami
            inx=strcmp(app.SelectedCountries.Items,app.SelectedCountries.Value);
            app.SelectedCountries.Items(inx)=[];
        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            %funkcja zarzadzajaca całoscia
            app.Save_plot= figure;
            app.Save_plot.Visible = 'off';
            %ustawianie zmiennej albo wykreslanie nowych przypadkow
            %zarazenia lub nowech smirci
            if(app.Cases.Value==true)
                Dataselect='New_cases';
            else
                Dataselect='New_deaths';
            end
            %czyszczenie usuwanie z kontenera
            app.Countries_conteiner.container=app.Countries_conteiner.Remove();
            
            %dodawanie wybranych krajow do kontenera
            for i=1:length(app.SelectedCountries.Items)
                country=app.Functions.SetCountryData(app.All_data,app.SelectedCountries.Items(i));
                app.Countries_conteiner.container=app.Countries_conteiner.Add(country);
                
            end
            %pobieranie danych z kontenera i dodawanie do wykresu
            
            %              [x,y]=app.Countries_conteiner.getdata(Dataselect);
            for i=1:length(app.SelectedCountries.Items)
                [x,y]=app.Countries_conteiner.getdata(Dataselect,i);
                [da,f]=app.prediction(x,y);
                if(app.Savetofile.Value==1)
                    app.save_1(x,y,da,f);
                end
                if(app.Predictiononemonth.Value==1)
                plot(app.UIAxes,[datetime(x) datetime(da)],[y f]);
                else
                plot(app.UIAxes,datetime(x),y); 
                end
                hold(app.UIAxes,'on');
            end
            legend(app.UIAxes,app.SelectedCountries.Items)
            hold(app.UIAxes,'off');
            %ustawianie wybranego przedziały czasowego
            if(app.Predictiononemonth.Value==1)
              app.UIAxes.XLim=[app.StartDay.Value app.EndDay.Value+30];  
            else
            app.UIAxes.XLim=[app.StartDay.Value app.EndDay.Value];
            end
            %zapisywanie do pliku
            if(app.Savetofile.Value==1)
                app.save_2();
            else
                
            end
            
            %             exportgraphics(app.UIFigure,'plot.png');
            
        end

        % Value changed function: EndDay
        function EndDayValueChanged(app, event)
            app.StartDay.Limits=[app.StartDay.Limits(1) app.EndDay.Value];
            %ustawianie limitu na dzien poczatkowy
        end

        % Value changed function: StartDay
        function StartDayValueChanged(app, event)
           app.EndDay.Limits = [app.StartDay.Value app.StartDay.Limits(2)];
            %ustawianie limitu na dzien koncowy
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Predictiononemonth = uicheckbox(app.UIFigure);
            app.Predictiononemonth.Text = 'Prediction one month';
            app.Savetofile = uicheckbox(app.UIFigure);
            app.Savetofile.Text = 'Save to file';
            app.StartDayDatePickerLabel = uilabel(app.UIFigure);
            app.StartDayDatePickerLabel.Text = 'Start Day';
            app.StartDay = uidatepicker(app.UIFigure);
            app.StartDay.Limits = [datetime([2020 1 1]) datetime([2021 1 22])];
            app.StartDay.ValueChangedFcn = createCallbackFcn(app, @StartDayValueChanged, true);
            app.StartDay.Value = datetime([2020 1 3]);
            app.Selectdata = uibuttongroup(app.UIFigure);
            app.Selectdata.Title = 'Select data';
            app.Cases = uiradiobutton(app.Selectdata);
            app.Cases.Text = 'Cases';
            app.Cases.Value = true;
            app.Deaths = uiradiobutton(app.Selectdata);
            app.Deaths.Text = 'Deaths';
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'Statistical analysis prograss of the pandemic';
            app.PLOTButton = uibutton(app.UIFigure, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.CountriesListBoxLabel = uilabel(app.UIFigure);
            app.CountriesListBoxLabel.Text = 'Countries';
            app.CountriesListBox = uilistbox(app.UIFigure);
            app.CountriesListBox.Items = {};
            app.CountriesListBox.ValueChangedFcn = createCallbackFcn(app, @CountriesListBoxValueChanged, true);
            app.CountriesListBox.Tooltip = {'Hold Ctrl to select few countries'};
            app.CountriesListBox.Value = {};
            app.SearchEditFieldLabel = uilabel(app.UIFigure);
            app.SearchEditFieldLabel.Text = 'Search';
            app.SearchEditField = uieditfield(app.UIFigure, 'text');
            app.SearchEditField.ValueChangingFcn = createCallbackFcn(app, @SearchEditFieldValueChanging, true);
            app.SelectedCountriesLabel = uilabel(app.UIFigure);
            app.SelectedCountriesLabel.Text = {'Selected'; 'Countries'};
            app.SelectedCountries = uilistbox(app.UIFigure);
            app.SelectedCountries.Items = {};
            app.SelectedCountries.ValueChangedFcn = createCallbackFcn(app, @SelectedCountriesValueChanged, true);
            app.SelectedCountries.Value = {};
            app.EndDayLabel = uilabel(app.UIFigure);
            app.EndDayLabel.Text = 'End Day';
            app.EndDay = uidatepicker(app.UIFigure);
            app.EndDay.Limits = [datetime([2020 1 1]) datetime([2021 1 22])];
            app.EndDay.ValueChangedFcn = createCallbackFcn(app, @EndDayValueChanged, true);
            app.EndDay.Value = datetime([2020 1 3]);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppDisign

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end